classdef appCalabrese < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Tree                         matlab.ui.container.CheckBoxTree
        OriginaleNode                matlab.ui.container.TreeNode
        PuntualeNode                 matlab.ui.container.TreeNode
        NegativoNode                 matlab.ui.container.TreeNode
        SenoNode                     matlab.ui.container.TreeNode
        EsponenzialeNode             matlab.ui.container.TreeNode
        LocaleNode                   matlab.ui.container.TreeNode
        MediamobileNode              matlab.ui.container.TreeNode
        EstrazionecontorniNode       matlab.ui.container.TreeNode
        GlobaleNode                  matlab.ui.container.TreeNode
        BilanciamentocoloreNode      matlab.ui.container.TreeNode
        CorrezionegammaNode          matlab.ui.container.TreeNode
        ImmaginedavisualizzareLabel  matlab.ui.control.Label
        CaricaImmagineButton         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CaricaImmagineButton
        function CaricaImmagineButtonPushed(app, event)
           % Carica l'immagine
[file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp;*.gif','Immagini'});
    if isequal(file,0)
        % l'utente ha annullato
        return;
    end
    
    % leggi immagine
    A = imread(fullfile(path, file));
[m,n,~]=size(A);


% Ottieni i nodi selezionati
checkedNodes = app.Tree.CheckedNodes;

% Controlla se il nodo "Negativo" è selezionato
if any(strcmp({checkedNodes.Text}, 'Negativo'))
    A = 255 - A;  % Filtro Negativo: inverte i colori
end

% Controlla se il nodo "Seno" è selezionato
if any(strcmp({checkedNodes.Text}, 'Seno'))
    A = uint8(round(255 * sin(double(A) / 255 * pi)));  % Filtro seno
end

% Controlla se il nodo "Esponenziale" è selezionato
if any(strcmp({checkedNodes.Text}, 'Esponenziale'))
    A = uint8(round(exp(log(256) / 255 * double(A)) - 1));  % Filtro esponenziale
end
% Controlla se il nodo "Media mobile" è selezionato
if any(strcmp({checkedNodes.Text}, 'Media mobile'))
    M=ones(3)/9;
    img=A;
    r=(length(M)-1)/2;
    for i=r+1:m-r
        for j=r+1:n-r
            img(i,j,:)=sum(sum(M.*double(A(i-1:i+1,j-1:j+1,:))));
        end
    end
    %così esce con doppio bordo
    img=abs(img);
    img(img>255)=255;
    img=uint8(img);
    A=img;
end

% Controlla se il nodo "Filtro contorni" è selezionato
if any(strcmp({checkedNodes.Text}, 'Estrazione contorni'))
    %M=[2,4,2;4,16,4;2,4,2]/40;
    % M=[0,1,0;-1,0,1;0,-1,0];

    A=double(A);
    B=A;
    B(2:end-1,2:end-1,:)=B(1:end-2,2:end-1,:)-B(2:end-1,1:end-2,:)-B(3:end,2:end-1,:)+B(2:end-1,3:end,:);
    A=B;
    
    % img=A;
    % r=(length(M)-1)/2;
    % for i=r+1:m-r
    %     for j=r+1:n-r
    %         img(i,j,:)=sum(sum(M.*double(A(i-1:i+1,j-1:j+1,:))));
    %     end
    % end
    % %così esce con doppio bordo
    % img=abs(img);
    % img(img>255)=255;
    % img=uint8(img);
    % A=img;
end

% Controlla se il nodo "Bilanciamento colore" è selezionato
if any(strcmp({checkedNodes.Text}, 'Bilanciamento colore'))
img0=double(A);
% Estrai i canali R, G, B
    R = img0(:,:,1);
    G = img0(:,:,2);
    B = img0(:,:,3);

    % Calcola la media di ogni canale
    Rm = mean(R(:));
    Gm = mean(G(:));
    Bm = mean(B(:));

    % Normalizza ogni canale rispetto alla sua media e scalalo alla media del canale verde
    R = R / Rm * Gm;
    B = B / Bm * Gm;

    img(:,:,1)=R;
    img(:,:,2)=G;
    img(:,:,3)=B;
    A=uint8(img);
end

if any(strcmp({checkedNodes.Text}, 'Correzione gamma'))
    A=double(A)/255;
    G=A(:,:,2);
    gamma=log(0.5)/log(mean(G(:)));
    A=A.^gamma;
    A=uint8(255*A);
end


% Visualizza l'immagine
imshow(A);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CaricaImmagineButton = uibutton(app.UIFigure, 'push');
            app.CaricaImmagineButton.ButtonPushedFcn = createCallbackFcn(app, @CaricaImmagineButtonPushed, true);
            app.CaricaImmagineButton.Text = 'Carica Immagine';
            app.ImmaginedavisualizzareLabel = uilabel(app.UIFigure);
            app.ImmaginedavisualizzareLabel.Text = {'Immagine da '; 'visualizzare'};
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.OriginaleNode = uitreenode(app.Tree);
            app.OriginaleNode.Text = 'Originale';
            app.PuntualeNode = uitreenode(app.Tree);
            app.PuntualeNode.Text = 'Puntuale';
            app.NegativoNode = uitreenode(app.PuntualeNode);
            app.NegativoNode.Text = 'Negativo';
            app.SenoNode = uitreenode(app.PuntualeNode);
            app.SenoNode.Text = 'Seno';
            app.EsponenzialeNode = uitreenode(app.PuntualeNode);
            app.EsponenzialeNode.Text = 'Esponenziale';
            app.LocaleNode = uitreenode(app.Tree);
            app.LocaleNode.Text = 'Locale';
            app.MediamobileNode = uitreenode(app.LocaleNode);
            app.MediamobileNode.Text = 'Media mobile';
            app.EstrazionecontorniNode = uitreenode(app.LocaleNode);
            app.EstrazionecontorniNode.Text = 'Estrazione contorni';
            app.GlobaleNode = uitreenode(app.Tree);
            app.GlobaleNode.Text = 'Globale';
            app.BilanciamentocoloreNode = uitreenode(app.GlobaleNode);
            app.BilanciamentocoloreNode.Text = 'Bilanciamento colore';
            app.CorrezionegammaNode = uitreenode(app.GlobaleNode);
            app.CorrezionegammaNode.Text = 'Correzione gamma';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.OriginaleNode];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appCalabrese

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end